classdef Funcaodetransferencia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        Label                 matlab.ui.control.Label            % Função d...
        Label2                matlab.ui.control.Label            % H(s) = _...
        Label3                matlab.ui.control.Label            % Preencha...
        LabelNumericEditField matlab.ui.control.Label            % a0
        valora0               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label4                matlab.ui.control.Label            % Y(s):
        Label5                matlab.ui.control.Label            % a1
        valora1               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label6                matlab.ui.control.Label            % a2
        valora2               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label7                matlab.ui.control.Label            % a3
        valora3               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label8                matlab.ui.control.Label            % a4
        valora4               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label9                matlab.ui.control.Label            % a5
        valora5               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label10               matlab.ui.control.Label            % X(s):
        Label11               matlab.ui.control.Label            % b0
        valorb0               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label12               matlab.ui.control.Label            % b1
        valorb1               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label13               matlab.ui.control.Label            % b2
        valorb2               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label14               matlab.ui.control.Label            % b3
        valorb3               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label15               matlab.ui.control.Label            % b4
        valorb4               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label16               matlab.ui.control.Label            % b5
        valorb5               matlab.ui.control.NumericEditField % [-Inf Inf]
        Play                  matlab.ui.control.Button           % Play
        UIAxes                matlab.ui.control.UIAxes           % Polinômi...
        LabelEditField        matlab.ui.control.Label            % Raízes
        EditField             matlab.ui.control.EditField       
        Label17               matlab.ui.control.Label            % Polos
        EditField2            matlab.ui.control.EditField       
        Label18               matlab.ui.control.Label            % Estabili...
        EditField3            matlab.ui.control.EditField       
        Label19               matlab.ui.control.Label            % Explicação
        EditField4            matlab.ui.control.EditField       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Play button pushed function
        function PlayButtonPushed(app)
        a0=app.valora0.Value; a1=app.valora1.Value; a2=app.valora2.Value; a3=app.valora3.Value; a4=app.valora4.Value; a5=app.valora5.Value;
        b0=app.valorb0.Value; b1=app.valorb1.Value; b2=app.valorb2.Value; b3=app.valorb3.Value; b4=app.valorb4.Value; b5=app.valorb5.Value;   
        v = [a5 a4 a3 a2 a1 a0];
        u = [b5 b4 b3 b2 b1 b0];
        r = roots(v)';
        s = roots(u)';
        g = sprintf( '%.3f%+.3fj,%.3f%+.3fj,%.3f%+.3fj,%.3f%+.3fj,%.3f%+.3fj',  [real(r(:)), imag(r(:))].');
        f = sprintf( '%.3f%+.3fj,%.3f%+.3fj,%.3f%+.3fj,%.3f%+.3fj,%.3f%+.3fj',  [real(s(:)), imag(s(:))].');
        x = -10:0.01:10;
        y = a5*(x.^5)+a4*(x.^4)+a3*(x.^3)+a2*(x.^2)+a1*x+a0;
        z = b5*(x.^5)+b4*(x.^4)+b3*(x.^3)+b2*(x.^2)+b1*x+b0;
        plot(app.UIAxes, x, z);
        app.EditField.Value = g;
        app.EditField2.Value = f;
        h = real(s);
        if h<0
            app.EditField3.Value = 'estável';
            app.EditField4.Value = 'todos os polos estão no semiplano esquerdo';
        elseif h==0
            app.EditField3.Value = 'estável';
            app.EditField4.Value = 'todos os polos estão no semiplano esquerdo';
        else
            app.EditField3.Value = 'instável';
            app.EditField4.Value = 'algum polo está no semiplano direito';
            
        end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Função de transferência';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = {'H(s) = __Y(s)__'; '               X(s)'};
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Preencha abaixo com os coeficientes de cada polinômio e aperte em play';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'a0';
            app.valora0 = uieditfield(app.UIFigure, 'numeric');
            app.valora0.Value = 4;
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Y(s):';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'a1';
            app.valora1 = uieditfield(app.UIFigure, 'numeric');
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'a2';
            app.valora2 = uieditfield(app.UIFigure, 'numeric');
            app.valora2.Value = 1;
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'a3';
            app.valora3 = uieditfield(app.UIFigure, 'numeric');
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'a4';
            app.valora4 = uieditfield(app.UIFigure, 'numeric');
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'a5';
            app.valora5 = uieditfield(app.UIFigure, 'numeric');
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'X(s):';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'b0';
            app.valorb0 = uieditfield(app.UIFigure, 'numeric');
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Text = 'b1';
            app.valorb1 = uieditfield(app.UIFigure, 'numeric');
            app.Label13 = uilabel(app.UIFigure);
            app.Label13.Text = 'b2';
            app.valorb2 = uieditfield(app.UIFigure, 'numeric');
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Text = 'b3';
            app.valorb3 = uieditfield(app.UIFigure, 'numeric');
            app.Label15 = uilabel(app.UIFigure);
            app.Label15.Text = 'b4';
            app.valorb4 = uieditfield(app.UIFigure, 'numeric');
            app.Label16 = uilabel(app.UIFigure);
            app.Label16.Text = 'b5';
            app.valorb5 = uieditfield(app.UIFigure, 'numeric');
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed);
            app.Play.Text = 'Play';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Polinômio do denominador');
            xlabel(app.UIAxes, 's');
            ylabel(app.UIAxes, 'X(s)');
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Raízes';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label17 = uilabel(app.UIFigure);
            app.Label17.Text = 'Polos';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.Label18 = uilabel(app.UIFigure);
            app.Label18.Text = 'Estabilidade';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.Label19 = uilabel(app.UIFigure);
            app.Label19.Text = 'Explicação';
            app.EditField4 = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Funcaodetransferencia()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end